classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        zSlider   matlab.ui.control.Slider
        ZLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            value = app.zSlider.Value;
            [X, Y, E_tmp] = Gaussian_Beam(value);
            surf(app.UIAxes, X, Y, E_tmp,'edgecolor','none')
            %plot(app.UIAxes, value*peaks)
            app.UIAxes.XLim = [min(min(X)), max(max(X))];
            app.UIAxes.YLim = [min(min(Y)), max(max(Y))];
            app.UIAxes.ZLim = [0, 450];
            app.UIAxes.Title.String = 'Cross section of a Gaussian Beam';
            %c = colorbar(app.UIAxes);
            app.UIAxes.CLim = [0,450];
            %colormap(app.UIAxes, jet); 
            %c.Label.String = 'Example Color Label'; 
            app.UIAxes.FontSize = 20; 
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            value = app.zSlider.Value;
            [X, Y, E_tmp] = Gaussian_Beam(value);
            surf(app.UIAxes, X, Y, E_tmp,'edgecolor','none')
            %plot(app.UIAxes, value*peaks)
            app.UIAxes.XLim = [min(min(X)), max(max(X))];
            app.UIAxes.YLim = [min(min(Y)), max(max(Y))];
            app.UIAxes.ZLim = [0, 450];
            app.UIAxes.Title.String = 'Cross section of a Gaussian Beam';
            %c = colorbar(app.UIAxes);
            app.UIAxes.CLim = [0,450];
            %colormap(app.UIAxes, jet); 
            %c.Label.String = 'Example Color Label'; 
            app.UIAxes.FontSize = 20; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cross-section of a Gaussian Beam')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-100 100];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end